classdef MideDistanciasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        Image                    matlab.ui.control.Image
        DistanciaEditFieldLabel  matlab.ui.control.Label
        DistanciaEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        IsMeasuring = false; % Flag to check if the app is on measuring
        currentTimer % Timer to get a measure from the camera
        videoInput = videoinput('winvideo',1); % Video input object to interact with the camera
    end
    
    methods (Access = private)
        
        function setupMeasureTimer(app)
            if isempty(app.currentTimer)
                app.currentTimer = timer( ...
                    'StartDelay', 0, ...
                    'Period', 0.1, ...
                    'TasksToExecute', Inf, ...
                    'ExecutionMode', 'fixedRate', ...
                    'UserData', app ...
                );
                app.currentTimer.TimerFcn = @app.measureCallback;
            end
            
            
            timerStatus = get(app.currentTimer, 'Running');
            if ~isequal(timerStatus ,'on')
                start(app.currentTimer);
            end
        end
        
        function stopMeasureTimer(app)
            timerStatus = get(app.currentTimer, 'Running');
            if isequal(timerStatus ,'on')
                stop(app.currentTimer);
            end
        end
        
        function measureCallback(app, ~, ~, ~)
            preview(app.videoInput, app.Image);
            im = getsnapshot(vid); 
            im = im(:,:,1);
            %imshow(im);
            
            r = [129, 21.9999999999999, 59, 137.000000000000];
            
            imROI = im(r(2):r(2)+r(4), r(1):r(1)+r(3));
            
            imshow(imROI);
            
            [row, ~] = find(imROI <= 125);
            mY = mean(row);
            
            interp = interpolacion_sensor(load('medidas2.mat').medidas, orden);
            
            distance = calcula_distancia(interp, mY);
            
            app.DistanciaEditField.Value = distance;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            if app.IsMeasuring
                app.PushTool.Icon = 'play-solid.png';
                app.IsMeasuring = false;
                app.stopMeasureTimer();
            else
                app.PushTool.Icon = 'stop-solid.png';
                app.IsMeasuring = true;
                app.setupMeasureTimer();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.IsMeasuring = false;
            if ~isempty(app.currentTimer)
                stop(app.currentTimer);
                delete(app.currentTimer);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'play-solid.png';
            app.Image = uiimage(app.UIFigure);
            app.DistanciaEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.UIFigure, 'text');
            app.DistanciaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MideDistanciasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end